classdef final_project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        NghiemTab                       matlab.ui.container.Tab
        TNDrawPlotButton                matlab.ui.control.Button
        TNLabel                         matlab.ui.control.Label
        TNButton                        matlab.ui.control.Button
        ChnphngphptmnghimDropDownLabel  matlab.ui.control.Label
        TNOptions                       matlab.ui.control.DropDown
        KtqunghimLabel                  matlab.ui.control.Label
        TNResultButton                  matlab.ui.control.NumericEditField
        SlnlpLabel                      matlab.ui.control.Label
        TNSoLanLapInput                 matlab.ui.control.NumericEditField
        NhpsaischophpEditFieldLabel     matlab.ui.control.Label
        TNSaiSoInput                    matlab.ui.control.NumericEditField
        NhpphngtrnhEditFieldLabel       matlab.ui.control.Label
        TNPhuongTrinhInput              matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        TNaInput                        matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        TNbInput                        matlab.ui.control.NumericEditField
        NoisuyTab                       matlab.ui.container.Tab
        NSUIAxes                        matlab.ui.control.UIAxes
        NhpxLabel_2                     matlab.ui.control.Label
        NSNhapX                         matlab.ui.control.EditField
        NhpYLabel                       matlab.ui.control.Label
        NSNhapY                         matlab.ui.control.EditField
        PhngphpnisuyDropDownLabel       matlab.ui.control.Label
        NSPP                            matlab.ui.control.DropDown
        KtquathcnisuyLabel              matlab.ui.control.Label
        NSDathuc                        matlab.ui.control.EditField
        NhpgitrcnnisuyLabel             matlab.ui.control.Label
        NSGiatri                        matlab.ui.control.NumericEditField
        KtqunisuyLabel                  matlab.ui.control.Label
        NSKetqua                        matlab.ui.control.NumericEditField
        NSButton                        matlab.ui.control.Button
        HoiquyTab                       matlab.ui.container.Tab
        HQDoThi                         matlab.ui.control.UIAxes
        NhpxLabel                       matlab.ui.control.Label
        HQNhapX                         matlab.ui.control.EditField
        NhpyLabel                       matlab.ui.control.Label
        HQNhapY                         matlab.ui.control.EditField
        HQMethod                        matlab.ui.control.Label
        HQTuyenTinh                     matlab.ui.control.Button
        HQHamMu                         matlab.ui.control.Button
        HQLogarit                       matlab.ui.control.Button
        PhngtrnhhiquyLabel              matlab.ui.control.Label
        HQPhuongTrinh                   matlab.ui.control.EditField
        NhpgitrdonLabel                 matlab.ui.control.Label
        HQDuDoan                        matlab.ui.control.NumericEditField
        KtqudonLabel                    matlab.ui.control.Label
        HQKQDuDoan                      matlab.ui.control.NumericEditField
        HQCalcButton                    matlab.ui.control.Button
        DaohamTab                       matlab.ui.container.Tab
        DHInputStep                     matlab.ui.control.NumericEditField
        NhpvaodliuyLabel_2              matlab.ui.control.Label
        ChonphngphapaohamXpxitrnluitrungtmDropDownLabel  matlab.ui.control.Label
        DHDeriveMethodsOption           matlab.ui.control.DropDown
        NhpgiatricntinhaohamEditFieldLabel  matlab.ui.control.Label
        DHCalculateValue                matlab.ui.control.NumericEditField
        KtquaEditFieldLabel             matlab.ui.control.Label
        DHResult                        matlab.ui.control.NumericEditField
        DHCalcButton                    matlab.ui.control.Button
        ChongiatrisaisOhhocOh2DropDownLabel  matlab.ui.control.Label
        DHSaiSoOptions                  matlab.ui.control.DropDown
        NhpvaodliuxEditFieldLabel       matlab.ui.control.Label
        DHInputX                        matlab.ui.control.EditField
        NhpvaodliuyEditFieldLabel       matlab.ui.control.Label
        DHInputY                        matlab.ui.control.EditField
        NhpvaohamsfxLabel               matlab.ui.control.Label
        DHInputFx                       matlab.ui.control.EditField
        TimaohamquahamsSwitchLabel      matlab.ui.control.Label
        DHSwitch                        matlab.ui.control.Switch
        TichphanTab                     matlab.ui.container.Tab
        TPCalcButton                    matlab.ui.control.Button
        ChonphngphapTchphnHnhthangSimpson13Simpson38Label  matlab.ui.control.Label
        TPChonPP                        matlab.ui.control.DropDown
        NhpcntnhtchphnLabel             matlab.ui.control.Label
        TPNhapcan                       matlab.ui.control.NumericEditField
        KtquaEditFieldLabel_2           matlab.ui.control.Label
        TPKetqua                        matlab.ui.control.NumericEditField
        NhpvaodliuxEditFieldLabel_2     matlab.ui.control.Label
        TPNhapX                         matlab.ui.control.EditField
        NhpvaodliuyEditFieldLabel_2     matlab.ui.control.Label
        TPNhapY                         matlab.ui.control.EditField
        NhpvaohamsfxLabel_2             matlab.ui.control.Label
        TPNhaphamso                     matlab.ui.control.EditField
        TimaohamquahamsSwitchLabel_2    matlab.ui.control.Label
        TPOnOff                         matlab.ui.control.Switch
        NhpNLabel                       matlab.ui.control.Label
        TPNhapN                         matlab.ui.control.NumericEditField
        GioiThieuNhomTab                matlab.ui.container.Tab
        NhmCamelCupLabel                matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
    end

    
    methods (Access = private)
        
        function vec = str2vector(app, source)
            num = textscan(source, '%f', 'Delimiter',',');
            vec = num{1}';
        end

        function DHStartUpFcn(app)
           app.DHSwitch.Value = 'Off';
           app.DHInputStep.Enable = 'off';
           app.DHInputFx.Enable = 'off';
           app.DHSaiSoOptions.Enable = 'off';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            DHStartUpFcn(app);
            t = readtable("DSTV.xlsx","Sheet",1);
            app.UITable.Data = t;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghiemTab = uitab(app.TabGroup);
            app.NghiemTab.Title = 'Nghiệm';
            app.TNDrawPlotButton = uibutton(app.NghiemTab, 'push');
            app.TNDrawPlotButton.Text = 'Vẽ đồ thị hàm số';
            app.TNLabel = uilabel(app.NghiemTab);
            app.TNLabel.Text = 'Nhập khoảng phân ly nghiệm [a,b]:';
            app.TNButton = uibutton(app.NghiemTab, 'push');
            app.TNButton.Text = 'Tìm nghiệm';
            app.ChnphngphptmnghimDropDownLabel = uilabel(app.NghiemTab);
            app.ChnphngphptmnghimDropDownLabel.Text = 'Chọn phương pháp tìm nghiệm';
            app.TNOptions = uidropdown(app.NghiemTab);
            app.TNOptions.Items = {'Chia đôi', 'Lặp', 'Newton'};
            app.TNOptions.Value = 'Chia đôi';
            app.KtqunghimLabel = uilabel(app.NghiemTab);
            app.KtqunghimLabel.Text = 'Kết quả nghiệm:';
            app.TNResultButton = uieditfield(app.NghiemTab, 'numeric');
            app.SlnlpLabel = uilabel(app.NghiemTab);
            app.SlnlpLabel.Text = 'Số lần lặp:';
            app.TNSoLanLapInput = uieditfield(app.NghiemTab, 'numeric');
            app.NhpsaischophpEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpsaischophpEditFieldLabel.Text = 'Nhập sai số cho phép';
            app.TNSaiSoInput = uieditfield(app.NghiemTab, 'numeric');
            app.NhpphngtrnhEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.TNPhuongTrinhInput = uieditfield(app.NghiemTab, 'numeric');
            app.aEditFieldLabel = uilabel(app.NghiemTab);
            app.aEditFieldLabel.Text = 'a:';
            app.TNaInput = uieditfield(app.NghiemTab, 'numeric');
            app.bEditFieldLabel = uilabel(app.NghiemTab);
            app.bEditFieldLabel.Text = 'b:';
            app.TNbInput = uieditfield(app.NghiemTab, 'numeric');
            app.NoisuyTab = uitab(app.TabGroup);
            app.NoisuyTab.Title = 'Nội suy';
            app.NSUIAxes = uiaxes(app.NoisuyTab);
            title(app.NSUIAxes, 'Đồ thị hàm số nội suy và dữ liệu thực')
            xlabel(app.NSUIAxes, 'X')
            ylabel(app.NSUIAxes, 'Y')
            zlabel(app.NSUIAxes, 'Z')
            app.NhpxLabel_2 = uilabel(app.NoisuyTab);
            app.NhpxLabel_2.Text = 'Nhập x';
            app.NSNhapX = uieditfield(app.NoisuyTab, 'text');
            app.NhpYLabel = uilabel(app.NoisuyTab);
            app.NhpYLabel.Text = 'Nhập y';
            app.NSNhapY = uieditfield(app.NoisuyTab, 'text');
            app.PhngphpnisuyDropDownLabel = uilabel(app.NoisuyTab);
            app.PhngphpnisuyDropDownLabel.Text = 'Phương pháp  nội suy';
            app.NSPP = uidropdown(app.NoisuyTab);
            app.NSPP.Items = {'Newton', 'Larange'};
            app.NSPP.Value = 'Newton';
            app.KtquathcnisuyLabel = uilabel(app.NoisuyTab);
            app.KtquathcnisuyLabel.Text = 'Kết quả đa thức nội suy';
            app.NSDathuc = uieditfield(app.NoisuyTab, 'text');
            app.NhpgitrcnnisuyLabel = uilabel(app.NoisuyTab);
            app.NhpgitrcnnisuyLabel.Text = 'Nhập giá trị cần nội suy';
            app.NSGiatri = uieditfield(app.NoisuyTab, 'numeric');
            app.KtqunisuyLabel = uilabel(app.NoisuyTab);
            app.KtqunisuyLabel.Text = 'Kết quả nội suy';
            app.NSKetqua = uieditfield(app.NoisuyTab, 'numeric');
            app.NSButton = uibutton(app.NoisuyTab, 'push');
            app.NSButton.Text = 'Tính';
            app.HoiquyTab = uitab(app.TabGroup);
            app.HoiquyTab.Title = 'Hồi quy';
            app.HQDoThi = uiaxes(app.HoiquyTab);
            title(app.HQDoThi, 'Đồ thị hồi quy')
            xlabel(app.HQDoThi, 'X')
            ylabel(app.HQDoThi, 'Y')
            zlabel(app.HQDoThi, 'Z')
            app.NhpxLabel = uilabel(app.HoiquyTab);
            app.NhpxLabel.Text = 'Nhập x';
            app.HQNhapX = uieditfield(app.HoiquyTab, 'text');
            app.NhpyLabel = uilabel(app.HoiquyTab);
            app.NhpyLabel.Text = 'Nhập y';
            app.HQNhapY = uieditfield(app.HoiquyTab, 'text');
            app.HQMethod = uilabel(app.HoiquyTab);
            app.HQMethod.Text = 'Chọn phương pháp hồi quy';
            app.HQTuyenTinh = uibutton(app.HoiquyTab, 'push');
            app.HQTuyenTinh.Text = 'Hồi quy tuyến tính';
            app.HQHamMu = uibutton(app.HoiquyTab, 'push');
            app.HQHamMu.Text = 'Hồi quy hàm mũ';
            app.HQLogarit = uibutton(app.HoiquyTab, 'push');
            app.HQLogarit.Text = 'Hồi quy hàm logarit';
            app.PhngtrnhhiquyLabel = uilabel(app.HoiquyTab);
            app.PhngtrnhhiquyLabel.Text = 'Phương trình hồi quy';
            app.HQPhuongTrinh = uieditfield(app.HoiquyTab, 'text');
            app.NhpgitrdonLabel = uilabel(app.HoiquyTab);
            app.NhpgitrdonLabel.Text = 'Nhập giá trị dự đoán';
            app.HQDuDoan = uieditfield(app.HoiquyTab, 'numeric');
            app.KtqudonLabel = uilabel(app.HoiquyTab);
            app.KtqudonLabel.Text = 'Kết quả dự đoán';
            app.HQKQDuDoan = uieditfield(app.HoiquyTab, 'numeric');
            app.HQCalcButton = uibutton(app.HoiquyTab, 'push');
            app.HQCalcButton.Text = 'Tính';
            app.DaohamTab = uitab(app.TabGroup);
            app.DaohamTab.Title = 'Đạo hàm';
            app.DHInputStep = uieditfield(app.DaohamTab, 'numeric');
            app.NhpvaodliuyLabel_2 = uilabel(app.DaohamTab);
            app.NhpvaodliuyLabel_2.Text = 'Nhập vào bước h';
            app.ChonphngphapaohamXpxitrnluitrungtmDropDownLabel = uilabel(app.DaohamTab);
            app.ChonphngphapaohamXpxitrnluitrungtmDropDownLabel.Text = {'Chọn phương pháp đạo hàm: Xấp xỉ trên,'; 'lùi, trung tâm'};
            app.DHDeriveMethodsOption = uidropdown(app.DaohamTab);
            app.DHDeriveMethodsOption.Items = {'Xấp xỉ trên', 'Lùi', 'Trung tâm'};
            app.DHDeriveMethodsOption.Value = 'Xấp xỉ trên';
            app.NhpgiatricntinhaohamEditFieldLabel = uilabel(app.DaohamTab);
            app.NhpgiatricntinhaohamEditFieldLabel.Text = 'Nhập giá trị cần tính đạo hàm';
            app.DHCalculateValue = uieditfield(app.DaohamTab, 'numeric');
            app.KtquaEditFieldLabel = uilabel(app.DaohamTab);
            app.KtquaEditFieldLabel.Text = 'Kết quả';
            app.DHResult = uieditfield(app.DaohamTab, 'numeric');
            app.DHCalcButton = uibutton(app.DaohamTab, 'push');
            app.DHCalcButton.Text = 'Tính';
            app.ChongiatrisaisOhhocOh2DropDownLabel = uilabel(app.DaohamTab);
            app.ChongiatrisaisOhhocOh2DropDownLabel.Text = {'Chọn giá trị sai số O(h) '; 'hoặc O(h^2) '};
            app.DHSaiSoOptions = uidropdown(app.DaohamTab);
            app.DHSaiSoOptions.Items = {'O(h)', 'O(h^2)'};
            app.DHSaiSoOptions.Value = 'O(h)';
            app.NhpvaodliuxEditFieldLabel = uilabel(app.DaohamTab);
            app.NhpvaodliuxEditFieldLabel.Text = 'Nhập vào dữ liệu x';
            app.DHInputX = uieditfield(app.DaohamTab, 'text');
            app.NhpvaodliuyEditFieldLabel = uilabel(app.DaohamTab);
            app.NhpvaodliuyEditFieldLabel.Text = 'Nhập vào dữ liệu y';
            app.DHInputY = uieditfield(app.DaohamTab, 'text');
            app.NhpvaohamsfxLabel = uilabel(app.DaohamTab);
            app.NhpvaohamsfxLabel.Text = 'Nhập vào hàm số f(x)';
            app.DHInputFx = uieditfield(app.DaohamTab, 'text');
            app.TimaohamquahamsSwitchLabel = uilabel(app.DaohamTab);
            app.TimaohamquahamsSwitchLabel.Text = 'Tìm đạo hàm qua hàm số';
            app.DHSwitch = uiswitch(app.DaohamTab, 'slider');
            app.TichphanTab = uitab(app.TabGroup);
            app.TichphanTab.Title = 'Tích phân';
            app.TPCalcButton = uibutton(app.TichphanTab, 'push');
            app.TPCalcButton.Text = 'Tính';
            app.ChonphngphapTchphnHnhthangSimpson13Simpson38Label = uilabel(app.TichphanTab);
            app.ChonphngphapTchphnHnhthangSimpson13Simpson38Label.Text = {'Chọn phương pháp Tích phân: Hình thang,'; 'Simpson 1/3 , Simpson 3/8'};
            app.TPChonPP = uidropdown(app.TichphanTab);
            app.TPChonPP.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.TPChonPP.Value = 'Simpson 1/3';
            app.NhpcntnhtchphnLabel = uilabel(app.TichphanTab);
            app.NhpcntnhtchphnLabel.Text = 'Nhập cận để tính tích phân';
            app.TPNhapcan = uieditfield(app.TichphanTab, 'numeric');
            app.KtquaEditFieldLabel_2 = uilabel(app.TichphanTab);
            app.KtquaEditFieldLabel_2.Text = 'Kết quả';
            app.TPKetqua = uieditfield(app.TichphanTab, 'numeric');
            app.NhpvaodliuxEditFieldLabel_2 = uilabel(app.TichphanTab);
            app.NhpvaodliuxEditFieldLabel_2.Text = 'Nhập vào dữ liệu x';
            app.TPNhapX = uieditfield(app.TichphanTab, 'text');
            app.NhpvaodliuyEditFieldLabel_2 = uilabel(app.TichphanTab);
            app.NhpvaodliuyEditFieldLabel_2.Text = 'Nhập vào dữ liệu y';
            app.TPNhapY = uieditfield(app.TichphanTab, 'text');
            app.NhpvaohamsfxLabel_2 = uilabel(app.TichphanTab);
            app.NhpvaohamsfxLabel_2.Text = 'Nhập vào hàm số f(x)';
            app.TPNhaphamso = uieditfield(app.TichphanTab, 'text');
            app.TimaohamquahamsSwitchLabel_2 = uilabel(app.TichphanTab);
            app.TimaohamquahamsSwitchLabel_2.Text = 'Tìm đạo hàm qua hàm số';
            app.TPOnOff = uiswitch(app.TichphanTab, 'slider');
            app.NhpNLabel = uilabel(app.TichphanTab);
            app.NhpNLabel.Text = 'Nhập N';
            app.TPNhapN = uieditfield(app.TichphanTab, 'numeric');
            app.GioiThieuNhomTab = uitab(app.TabGroup);
            app.GioiThieuNhomTab.Title = 'Giới thiệu nhóm';
            app.NhmCamelCupLabel = uilabel(app.GioiThieuNhomTab);
            app.NhmCamelCupLabel.Text = 'Nhóm CamelCup';
            app.UITable = uitable(app.GioiThieuNhomTab);
            app.UITable.ColumnName = {'STT'; 'MSSV'; 'Họ và tên'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = final_project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end